classdef MammographySimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Generate2DImagefor3DPhantomButton  matlab.ui.control.Button
        Generate1DProfileFrom2DphantomButton  matlab.ui.control.Button
        AngleindegreesDropDown     matlab.ui.control.DropDown
        AngleindegreesLabel        matlab.ui.control.Label
        DistanceincmDropDown       matlab.ui.control.DropDown
        DistanceincmDropDownLabel  matlab.ui.control.Label
        EnergyDropDown             matlab.ui.control.DropDown
        EnergyDropDownLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function phantom = generatePhantom(app)
            % Manually create a simple circular phantom
            [X, Y] = meshgrid(linspace(-1, 1, 256), linspace(-1, 1, 256));
            phantom = sqrt(X.^2 + Y.^2) <= 0.5; % Simple circular mask
        end

        function image2D = simulateXRay(app,phantom, energy, angle, distance)
            % Adjust µ values based on energy (example values)
            muValues = [0.02, 0.04, 0.06]; % for 50, 75, 100 keV
            mu = muValues((energy / 25) - 1);
            
            % Rotate phantom based on angle
            rotatedPhantom = imrotate(phantom, angle, 'bilinear', 'crop');
            
            % Simulate X-ray attenuation
            image2D = exp(-mu * rotatedPhantom * distance);  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Generate1DProfileFrom2DphantomButton
        function Generate1DProfileFrom2DphantomButtonPushed(app, event)
            energy = str2double(app.EnergyDropDown.Value);
            angle = str2double(app.AngleindegreesDropDown.Value);
            distance = str2double(app.DistanceincmDropDown.Value);
            
            % Generate phantom
            phantom = app.generatePhantom();
            
            % Simulate X-ray image
            image2D = app.simulateXRay(phantom, energy, angle, distance);
            
            % Generate 1D profile
            profile1D = sum(image2D, 1); % Summing along rows for profile
            
            % Plot profile
            plot(app.UIAxes, profile1D);
            title(app.UIAxes, '1D Profile');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EnergyDropDownLabel = uilabel(app.UIFigure);
            app.EnergyDropDownLabel.Text = 'Energy';
            app.EnergyDropDown = uidropdown(app.UIFigure);
            app.EnergyDropDown.Items = {'50', '75', '100'};
            app.EnergyDropDown.Value = '50';
            app.DistanceincmDropDownLabel = uilabel(app.UIFigure);
            app.DistanceincmDropDownLabel.Text = 'Distance in cm';
            app.DistanceincmDropDown = uidropdown(app.UIFigure);
            app.DistanceincmDropDown.Items = {'50', '100', '150', '200'};
            app.DistanceincmDropDown.Value = '50';
            app.AngleindegreesLabel = uilabel(app.UIFigure);
            app.AngleindegreesLabel.Text = {'Angle'; ' in degrees'};
            app.AngleindegreesDropDown = uidropdown(app.UIFigure);
            app.AngleindegreesDropDown.Items = {'15', '30', '45', '60', '90'};
            app.AngleindegreesDropDown.Value = '15';
            app.Generate1DProfileFrom2DphantomButton = uibutton(app.UIFigure, 'push');
            app.Generate1DProfileFrom2DphantomButton.ButtonPushedFcn = createCallbackFcn(app, @Generate1DProfileFrom2DphantomButtonPushed, true);
            app.Generate1DProfileFrom2DphantomButton.Text = {'Generate 1D Profile '; 'From 2D phantom'};
            app.Generate2DImagefor3DPhantomButton = uibutton(app.UIFigure, 'push');
            app.Generate2DImagefor3DPhantomButton.Text = {'Generate 2D Image '; 'for 3D Phantom'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MammographySimulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end